classdef TestGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        KUKAxyzControlButtonGroup  matlab.ui.container.ButtonGroup
        ZButton_2                  matlab.ui.control.ToggleButton
        ZButton                    matlab.ui.control.ToggleButton
        YButton_2                  matlab.ui.control.ToggleButton
        YButton                    matlab.ui.control.ToggleButton
        XButton_2                  matlab.ui.control.ToggleButton
        XButton                    matlab.ui.control.ToggleButton
        PauseButton                matlab.ui.control.ToggleButton
        ReloadModelsButton         matlab.ui.control.Button
        DoBotCompleteTaskAButton   matlab.ui.control.Button
        DoBotCompleteTaskBButton   matlab.ui.control.Button
        KUKACompleteTaskBButton    matlab.ui.control.Button
        KUKACompleteTaskAButton    matlab.ui.control.Button
        ResetCheckBox              matlab.ui.control.CheckBox
        EMERGENCYSTOPButton        matlab.ui.control.Button
    end

    
    properties (Access = private)
        modelKUKA % KUKA model location
        modelDoBot % DoBot model location
        qn_K = zeros(1,7);
        qn_D = zeros(1,4);
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function init(app)
            figure;
            app.modelKUKA = KUKAiiwa7;
            app.modelKUKA.model.animate(app.qn_K)
            drawnow();
        end

        % Button pushed function: KUKACompleteTaskAButton
        function KUKACompleteTaskAButtonPushed(app, event)
            q1 = app.modelKUKA.model.getpos;
            q2 = [2.4511   -0.8651    2.3865   -1.2566    1.1868    1.0891   -1.2828];
            qMatrix = jtraj(q1,q2,100);
            app.modelKUKA.model.animate(qMatrix)
        end

        % Button pushed function: KUKACompleteTaskBButton
        function KUKACompleteTaskBButtonPushed(app, event)
            q1 = app.modelKUKA.model.getpos;
            q2 = [1.1610    1.2566   -1.1868    1.0891    2.3736    0.8378    1.2217];
            qMatrix = jtraj(q1,q2,100);
            app.modelKUKA.model.animate(qMatrix)
        end

        % Button pushed function: ReloadModelsButton
        function ReloadModelsButtonPushed(app, event)
            figure;
            app.modelKUKA.model.delete;
            app.modelKUKA = KUKAiiwa7;
            app.modelKUKA.model.animate(app.qn_K)
            drawnow();
        end

        % Button down function: KUKAxyzControlButtonGroup
        function KUKAxyzControlButtonGroupButtonDown(app, event)
          
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.EMERGENCYSTOPButton = uibutton(app.UIFigure, 'push');
            app.EMERGENCYSTOPButton.Text = {'EMERGENCY'; 'STOP'};
            app.ResetCheckBox = uicheckbox(app.UIFigure);
            app.ResetCheckBox.Text = 'Reset';
            app.KUKACompleteTaskAButton = uibutton(app.UIFigure, 'push');
            app.KUKACompleteTaskAButton.ButtonPushedFcn = createCallbackFcn(app, @KUKACompleteTaskAButtonPushed, true);
            app.KUKACompleteTaskAButton.Text = {'KUKA'; 'Complete Task A'};
            app.KUKACompleteTaskBButton = uibutton(app.UIFigure, 'push');
            app.KUKACompleteTaskBButton.ButtonPushedFcn = createCallbackFcn(app, @KUKACompleteTaskBButtonPushed, true);
            app.KUKACompleteTaskBButton.Text = {'KUKA'; 'Complete Task B'};
            app.DoBotCompleteTaskBButton = uibutton(app.UIFigure, 'push');
            app.DoBotCompleteTaskBButton.Text = {'DoBot'; 'Complete Task B'};
            app.DoBotCompleteTaskAButton = uibutton(app.UIFigure, 'push');
            app.DoBotCompleteTaskAButton.Text = {'DoBot'; 'Complete Task A'};
            app.ReloadModelsButton = uibutton(app.UIFigure, 'push');
            app.ReloadModelsButton.ButtonPushedFcn = createCallbackFcn(app, @ReloadModelsButtonPushed, true);
            app.ReloadModelsButton.Text = 'Reload Models';
            app.KUKAxyzControlButtonGroup = uibuttongroup(app.UIFigure);
            app.KUKAxyzControlButtonGroup.Title = 'KUKA x,y,z Control';
            app.KUKAxyzControlButtonGroup.ButtonDownFcn = createCallbackFcn(app, @KUKAxyzControlButtonGroupButtonDown, true);
            app.PauseButton = uitogglebutton(app.KUKAxyzControlButtonGroup);
            app.PauseButton.Text = 'Pause';
            app.XButton = uitogglebutton(app.KUKAxyzControlButtonGroup);
            app.XButton.Text = '+X';
            app.XButton.Value = true;
            app.XButton_2 = uitogglebutton(app.KUKAxyzControlButtonGroup);
            app.XButton_2.Text = '-X';
            app.YButton = uitogglebutton(app.KUKAxyzControlButtonGroup);
            app.YButton.Text = '+Y';
            app.YButton_2 = uitogglebutton(app.KUKAxyzControlButtonGroup);
            app.YButton_2.Text = '-Y';
            app.ZButton = uitogglebutton(app.KUKAxyzControlButtonGroup);
            app.ZButton.Text = '+Z';
            app.ZButton_2 = uitogglebutton(app.KUKAxyzControlButtonGroup);
            app.ZButton_2.Text = '-Z';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TestGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @init)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end